classdef ControlInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HHController                    matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        AutoZeroButton                  matlab.ui.control.Button
        XoffsetEditFieldLabel           matlab.ui.control.Label
        XoffsetEditField                matlab.ui.control.NumericEditField
        YoffsetEditFieldLabel           matlab.ui.control.Label
        YoffsetEditField                matlab.ui.control.NumericEditField
        ZoffsetEditFieldLabel           matlab.ui.control.Label
        ZoffsetEditField                matlab.ui.control.NumericEditField
        CurrentAGaugeLabel              matlab.ui.control.Label
        CurrentAGauge                   matlab.ui.control.NinetyDegreeGauge
        CoilCurrentALabel               matlab.ui.control.Label
        YCurrentGaugeLabel              matlab.ui.control.Label
        YCurrentGauge                   matlab.ui.control.SemicircularGauge
        XCurrentGaugeLabel              matlab.ui.control.Label
        XCurrentGauge                   matlab.ui.control.SemicircularGauge
        ZCurrentGaugeLabel              matlab.ui.control.Label
        ZCurrentGauge                   matlab.ui.control.SemicircularGauge
        NoiseMonitorLabel               matlab.ui.control.Label
        TimeStepsGaugeLabel             matlab.ui.control.Label
        TimeStepsGauge                  matlab.ui.control.NinetyDegreeGauge
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.NumericEditField
        XoffsetEditFieldLabel_2         matlab.ui.control.Label
        XgainField                      matlab.ui.control.NumericEditField
        XoffsetEditFieldLabel_3         matlab.ui.control.Label
        YgainField                      matlab.ui.control.NumericEditField
        ZgainField                      matlab.ui.control.NumericEditField
        XoffsetEditFieldLabel_4         matlab.ui.control.Label
        autoLamp                        matlab.ui.control.Lamp
        RightPanel                      matlab.ui.container.Panel
        YField1GaugeLabel               matlab.ui.control.Label
        YField1Gauge                    matlab.ui.control.LinearGauge
        XField1GaugeLabel               matlab.ui.control.Label
        XField1Gauge                    matlab.ui.control.LinearGauge
        ZField1GaugeLabel               matlab.ui.control.Label
        ZField1Gauge                    matlab.ui.control.LinearGauge
        YField2GaugeLabel               matlab.ui.control.Label
        YField2Gauge                    matlab.ui.control.LinearGauge
        XField2GaugeLabel               matlab.ui.control.Label
        XField2Gauge                    matlab.ui.control.LinearGauge
        ZField2GaugeLabel               matlab.ui.control.Label
        ZField2Gauge                    matlab.ui.control.LinearGauge
        InternalMagneticFielduTLabel    matlab.ui.control.Label
        AmbientMagneticFielduTLabel     matlab.ui.control.Label
        NoiseMonitorsLabel              matlab.ui.control.Label
        InternalBFielddBuTGaugeLabel    matlab.ui.control.Label
        InternalBFielddBuTGauge         matlab.ui.control.NinetyDegreeGauge
        ExternalBFielddBuTGaugeLabel    matlab.ui.control.Label
        ExternalBFielddBuTGauge         matlab.ui.control.NinetyDegreeGauge
        ClearPlotButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        MainControlSwitch               matlab.ui.control.Switch
        FileforAutomaticEditFieldLabel  matlab.ui.control.Label
        FileforAutomaticEditField       matlab.ui.control.EditField
        XFieldSliderLabel               matlab.ui.control.Label
        XFieldSlider                    matlab.ui.control.Slider
        YFieldSliderLabel               matlab.ui.control.Label
        YFieldSlider                    matlab.ui.control.Slider
        ZFieldSliderLabel               matlab.ui.control.Label
        ZFieldSlider                    matlab.ui.control.Slider
        FieldRequestinuTLabel           matlab.ui.control.Label
        MainControlSwitchLabel          matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        AutoUpdateLamp                  matlab.ui.control.Lamp
        ModeDropDownLabel               matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        CorrectforAmbientFieldVariationSwitchLabel  matlab.ui.control.Label
        CorrectforAmbientFieldVariationSwitch  matlab.ui.control.Switch
        StartButton                     matlab.ui.control.Button
        ModeDropDownLabel_2             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        comm_port
        port_opened
        
        tPrev;
        dT;

        %Objects for plotting
        xB_track1
        xB_track2
        yB_track1
        yB_track2
        zB_track1
        zB_track2
        xI_track
        yI_track
        zI_track
        
        x_line
        y_line
        z_line
        
        x_line2
        y_line2
        z_line2
        
        %Gain values in uT/A
        gain=[32.22 33.39 31.13];        
        
        outside_B=[0 0 0];
        
        procTime=0;
        runSchedule;
        
        curInd=1;
        
    end
    
    methods (Access = private)
        function data=main_update(app)
            if app.ModeDropDown.Value=="Automatic"
                app.procTime=toc;
                dif=app.procTime-app.runSchedule(app.curInd,1);
                %Increment through schedule until we catch up to where we
                %are in the schedule
                while dif>0
                    app.curInd=app.curInd+1;
                    dif=app.procTime-app.runSchedule(app.curInd,1);
                end
                
                
                if app.procTime>app.runSchedule(end,1)
                    app.MainControlSwitch.Value="OFF";
                end
                
                app.XFieldSlider.Value=app.runSchedule(app.curInd,2);
                app.YFieldSlider.Value=app.runSchedule(app.curInd,3);
                app.ZFieldSlider.Value=app.runSchedule(app.curInd,4);
                
                app.TimeEditField.Value=toc;
            end
            
            pause(0.01);
            offset=[app.XoffsetEditField.Value app.YoffsetEditField.Value app.ZoffsetEditField.Value];
            reqB=[app.XFieldSlider.Value app.YFieldSlider.Value app.ZFieldSlider.Value] + offset;
            if app.CorrectforAmbientFieldVariationSwitch.Value=="On"
                delB=[app.XField2Gauge.Value app.YField2Gauge.Value app.ZField2Gauge.Value]-app.outside_B;
                reqB=reqB+delB;
            end
            
            app.gain=[app.XgainField.Value app.YgainField.Value app.ZgainField.Value];
            reqI=reqB./app.gain;
            toSend=reqI;
            
            
            data_len=9;
            data = zeros(data_len);
            pause(0.05);
            if not(app.port_opened)
                data="error";
                return
            end
            cmd = sprintf('%f,%f,%f', toSend(1),toSend(2),toSend(3));
            fwrite(app.comm_port,cmd);
            for i=1:data_len
                data(i) = fscanf(app.comm_port,'%f');
            end
            app.XField1Gauge.Value=data(1);
            app.YField1Gauge.Value=data(2);
            app.ZField1Gauge.Value=data(3);
            app.XField2Gauge.Value=data(4);
            app.YField2Gauge.Value=data(5);
            app.ZField2Gauge.Value=data(6);
            app.XCurrentGauge.Value=data(7);
            app.YCurrentGauge.Value=data(8);
            app.ZCurrentGauge.Value=data(9);
            
            app.xB_track1 = circshift(app.xB_track1,1);
            app.xB_track1(1)=data(1);
            app.yB_track1 = circshift(app.yB_track1,1);
            app.yB_track1(1)=data(2);
            app.zB_track1 = circshift(app.zB_track1,1);
            app.zB_track1(1)=data(3);
            
            app.xB_track2 = circshift(app.xB_track2,1);
            app.xB_track2(1)=data(4);
            app.yB_track2 = circshift(app.yB_track2,1);
            app.yB_track2(1)=data(5);
            app.zB_track2 = circshift(app.zB_track2,1);
            app.zB_track2(1)=data(6);
            
            app.InternalBFielddBuTGauge.Value = mag2db(rssq([rms(app.xB_track1-mean(app.xB_track1)) rms(app.yB_track1-mean(app.yB_track1)) rms(app.zB_track1-mean(app.zB_track1))])+1e-10);
            app.ExternalBFielddBuTGauge.Value = mag2db(rssq([rms(app.xB_track2-mean(app.xB_track2)) rms(app.yB_track2-mean(app.yB_track2)) rms(app.zB_track2-mean(app.zB_track2))])+1e-10);
            app.CurrentAGauge.Value = mag2db(rssq([rms(app.xI_track-mean(app.xI_track)) rms(app.yI_track-mean(app.yI_track)) rms(app.zI_track-mean(app.zI_track))])+1e-10);
    
    
            addpoints(app.x_line,toc,data(1))
            addpoints(app.y_line,toc,data(2))
            addpoints(app.z_line,toc,data(3))
            
            addpoints(app.x_line2,toc,data(4))
            addpoints(app.y_line2,toc,data(5))
            addpoints(app.z_line2,toc,data(6))
            drawnow
            
            app.dT=toc-app.tPrev;
            app.tPrev=toc;
            app.TimeStepsGauge.Value=app.dT;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MainControlSwitch
        function MainControlSwitchValueChanged(app, event)
            value = app.MainControlSwitch.Value;
            if value=="ON"
                app.AutoUpdateLamp.Color='yellow';
                pause(0.01);
                
                %Vectors to keep track of noise 
                noise_vec_length=10;
                app.xB_track1=zeros(1,noise_vec_length);
                app.xB_track2=zeros(1,noise_vec_length);
                app.yB_track1=zeros(1,noise_vec_length);
                app.yB_track2=zeros(1,noise_vec_length);
                app.zB_track1=zeros(1,noise_vec_length);
                app.zB_track2=zeros(1,noise_vec_length);
                app.xI_track=zeros(1,noise_vec_length);
                app.yI_track=zeros(1,noise_vec_length);
                app.zI_track=zeros(1,noise_vec_length);
                
                plot_lim=250;
                app.x_line = animatedline(app.UIAxes, 'MaximumNumPoints',plot_lim, 'Color', 'r');
                app.y_line = animatedline(app.UIAxes, 'MaximumNumPoints',plot_lim, 'Color', 'g');
                app.z_line = animatedline(app.UIAxes, 'MaximumNumPoints',plot_lim, 'Color', 'b');
                
                app.x_line2 = animatedline(app.UIAxes, 'MaximumNumPoints',plot_lim, 'Color', 'r', 'LineStyle', ':');
                app.y_line2 = animatedline(app.UIAxes, 'MaximumNumPoints',plot_lim, 'Color', 'g', 'LineStyle', ':');
                app.z_line2 = animatedline(app.UIAxes, 'MaximumNumPoints',plot_lim, 'Color', 'b', 'LineStyle', ':');
                
                if ~isempty(instrfind)
                    fclose(instrfind);
                        delete(instrfind);
                end
                
                app.comm_port = serial('COM4','BaudRate',250000);
                fopen(app.comm_port);
                app.port_opened=true;
                
                if app.ModeDropDown.Value=="Automatic"
                    app.runSchedule=readmatrix(app.FileforAutomaticEditField.Value);
                end
                
                app.AutoUpdateLamp.Color='green';
                pause(0.01);
            end

            if value=="OFF"
                app.AutoUpdateLamp.Color='red';
                pause(0.01);
                fclose(app.comm_port);
                app.port_opened=false;
            end
        end

        % Button pushed function: AutoZeroButton
        function AutoZeroButtonPushed(app, event)
            tic
            app.tPrev=toc;
            
            app.autoLamp.Color='yellow';
            
            app.XFieldSlider.Value=0;
            app.YFieldSlider.Value=0;
            app.ZFieldSlider.Value=0;
            
            main_update(app);
            
            defaultGain=30;
            
            app.XgainField.Value=defaultGain;
            app.YgainField.Value=defaultGain;
            app.ZgainField.Value=defaultGain;
            
            main_update(app);
            main_update(app);
            
            defaultValue=30;
            
            app.XFieldSlider.Value=defaultValue;
            app.YFieldSlider.Value=defaultValue;
            app.ZFieldSlider.Value=defaultValue;
            
            main_update(app);
            main_update(app);
            
            positive=[app.XField1Gauge.Value app.YField1Gauge.Value app.ZField1Gauge.Value];
            
            app.XFieldSlider.Value=-defaultValue;
            app.YFieldSlider.Value=-defaultValue;
            app.ZFieldSlider.Value=-defaultValue;
            
            main_update(app);
            main_update(app);
            
            negative=[app.XField1Gauge.Value app.YField1Gauge.Value app.ZField1Gauge.Value];
            
            main_update(app);
            main_update(app);
            
            myGain=defaultGain*((positive-negative)/2)/defaultValue;
            app.XgainField.Value=myGain(1);
            app.YgainField.Value=myGain(2);
            app.ZgainField.Value=myGain(3);
            
            main_update(app);
            main_update(app);
            
            app.autoLamp.Color='yellow';
            
            app.XFieldSlider.Value=0;
            app.YFieldSlider.Value=0;
            app.ZFieldSlider.Value=0;
            
            zeroed=false;
            Pterm=-0.3;
            while ~zeroed
                main_update(app);
                main_update(app);
                pause(0.1);
                app.XoffsetEditField.Value = app.XoffsetEditField.Value + Pterm*app.XField1Gauge.Value;
                app.YoffsetEditField.Value = app.YoffsetEditField.Value + Pterm*app.YField1Gauge.Value;
                app.ZoffsetEditField.Value = app.ZoffsetEditField.Value + Pterm*app.ZField1Gauge.Value;
                
                if norm([app.XField1Gauge.Value app.YField1Gauge.Value app.ZField1Gauge.Value])<2
                    zeroed=true;
                end
            end
            
            app.outside_B=[app.XField2Gauge.Value app.YField2Gauge.Value app.ZField2Gauge.Value];
            
            app.autoLamp.Color='green';
            
            tic
            app.tPrev=toc;
            main_update(app);
        end

        % Value changed function: XoffsetEditField
        function XoffsetEditFieldValueChanged(app, event)
            value = app.XoffsetEditField.Value;
            
        end

        % Value changed function: YoffsetEditField
        function YoffsetEditFieldValueChanged(app, event)
            value = app.YoffsetEditField.Value;
            
        end

        % Value changed function: ZoffsetEditField
        function ZoffsetEditFieldValueChanged(app, event)
            value = app.ZoffsetEditField.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            tic
            app.tPrev=toc;
           
            %Infinite loop of running
            while(app.MainControlSwitch.Value=="ON")
                main_update(app);
            end
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            clearpoints(app.x_line);
            clearpoints(app.y_line);
            clearpoints(app.z_line);
            drawnow;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.HHController.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {493, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HHController = uifigure('Visible', 'off');
            app.HHController.Name = 'Pulse Generator';
            app.HHController.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.HHController);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AutoZeroButton = uibutton(app.LeftPanel, 'push');
            app.AutoZeroButton.ButtonPushedFcn = createCallbackFcn(app, @AutoZeroButtonPushed, true);
            app.AutoZeroButton.Text = 'Auto-Zero';
            app.XoffsetEditFieldLabel = uilabel(app.LeftPanel);
            app.XoffsetEditFieldLabel.Text = 'X-offset';
            app.XoffsetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @XoffsetEditFieldValueChanged, true);
            app.YoffsetEditFieldLabel = uilabel(app.LeftPanel);
            app.YoffsetEditFieldLabel.Text = 'Y-offset';
            app.YoffsetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @YoffsetEditFieldValueChanged, true);
            app.ZoffsetEditFieldLabel = uilabel(app.LeftPanel);
            app.ZoffsetEditFieldLabel.Text = 'Z-offset';
            app.ZoffsetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @ZoffsetEditFieldValueChanged, true);
            app.CurrentAGaugeLabel = uilabel(app.LeftPanel);
            app.CurrentAGaugeLabel.Text = 'Current (A)';
            app.CurrentAGauge = uigauge(app.LeftPanel, 'ninetydegree');
            app.CurrentAGauge.Limits = [-20 20];
            app.CoilCurrentALabel = uilabel(app.LeftPanel);
            app.CoilCurrentALabel.Text = 'Coil Current (A)';
            app.YCurrentGaugeLabel = uilabel(app.LeftPanel);
            app.YCurrentGaugeLabel.Text = 'Y-Current';
            app.YCurrentGauge = uigauge(app.LeftPanel, 'semicircular');
            app.YCurrentGauge.Limits = [-10 10];
            app.XCurrentGaugeLabel = uilabel(app.LeftPanel);
            app.XCurrentGaugeLabel.Text = 'X-Current';
            app.XCurrentGauge = uigauge(app.LeftPanel, 'semicircular');
            app.XCurrentGauge.Limits = [-10 10];
            app.ZCurrentGaugeLabel = uilabel(app.LeftPanel);
            app.ZCurrentGaugeLabel.Text = 'Z-Current';
            app.ZCurrentGauge = uigauge(app.LeftPanel, 'semicircular');
            app.ZCurrentGauge.Limits = [-10 10];
            app.NoiseMonitorLabel = uilabel(app.LeftPanel);
            app.NoiseMonitorLabel.Text = 'Noise Monitor';
            app.TimeStepsGaugeLabel = uilabel(app.LeftPanel);
            app.TimeStepsGaugeLabel.Text = 'Time Step (s)';
            app.TimeStepsGauge = uigauge(app.LeftPanel, 'ninetydegree');
            app.TimeStepsGauge.Limits = [0 1];
            app.TimeEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XoffsetEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.XoffsetEditFieldLabel_2.Text = 'X-gain';
            app.XgainField = uieditfield(app.LeftPanel, 'numeric');
            app.XgainField.Value = 32.22;
            app.XoffsetEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.XoffsetEditFieldLabel_3.Text = 'Y-gain';
            app.YgainField = uieditfield(app.LeftPanel, 'numeric');
            app.YgainField.Value = 33.39;
            app.ZgainField = uieditfield(app.LeftPanel, 'numeric');
            app.ZgainField.Value = 31.13;
            app.XoffsetEditFieldLabel_4 = uilabel(app.LeftPanel);
            app.XoffsetEditFieldLabel_4.Text = 'Z-gain';
            app.autoLamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.YField1GaugeLabel = uilabel(app.RightPanel);
            app.YField1GaugeLabel.Text = 'Y-Field1';
            app.YField1Gauge = uigauge(app.RightPanel, 'linear');
            app.YField1Gauge.Limits = [-100 100];
            app.XField1GaugeLabel = uilabel(app.RightPanel);
            app.XField1GaugeLabel.Text = 'X-Field1';
            app.XField1Gauge = uigauge(app.RightPanel, 'linear');
            app.XField1Gauge.Limits = [-100 100];
            app.ZField1GaugeLabel = uilabel(app.RightPanel);
            app.ZField1GaugeLabel.Text = 'Z-Field1';
            app.ZField1Gauge = uigauge(app.RightPanel, 'linear');
            app.ZField1Gauge.Limits = [-100 100];
            app.YField2GaugeLabel = uilabel(app.RightPanel);
            app.YField2GaugeLabel.Text = 'Y-Field2';
            app.YField2Gauge = uigauge(app.RightPanel, 'linear');
            app.YField2Gauge.Limits = [-100 100];
            app.XField2GaugeLabel = uilabel(app.RightPanel);
            app.XField2GaugeLabel.Text = 'X-Field2';
            app.XField2Gauge = uigauge(app.RightPanel, 'linear');
            app.XField2Gauge.Limits = [-100 100];
            app.ZField2GaugeLabel = uilabel(app.RightPanel);
            app.ZField2GaugeLabel.Text = 'Z-Field2';
            app.ZField2Gauge = uigauge(app.RightPanel, 'linear');
            app.ZField2Gauge.Limits = [-100 100];
            app.InternalMagneticFielduTLabel = uilabel(app.RightPanel);
            app.InternalMagneticFielduTLabel.Text = 'Internal Magnetic Field (uT)';
            app.AmbientMagneticFielduTLabel = uilabel(app.RightPanel);
            app.AmbientMagneticFielduTLabel.Text = 'Ambient Magnetic Field (uT)';
            app.NoiseMonitorsLabel = uilabel(app.RightPanel);
            app.NoiseMonitorsLabel.Text = 'Noise Monitors';
            app.InternalBFielddBuTGaugeLabel = uilabel(app.RightPanel);
            app.InternalBFielddBuTGaugeLabel.Text = 'Internal B-Field (dBuT)';
            app.InternalBFielddBuTGauge = uigauge(app.RightPanel, 'ninetydegree');
            app.InternalBFielddBuTGauge.Limits = [-20 20];
            app.ExternalBFielddBuTGaugeLabel = uilabel(app.RightPanel);
            app.ExternalBFielddBuTGaugeLabel.Text = 'External B-Field (dBuT)';
            app.ExternalBFielddBuTGauge = uigauge(app.RightPanel, 'ninetydegree');
            app.ExternalBFielddBuTGauge.Limits = [-20 20];
            app.ClearPlotButton = uibutton(app.RightPanel, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'B-Field History')
            xlabel(app.UIAxes, 'T (s)')
            ylabel(app.UIAxes, 'B (uT)')
            app.MainControlSwitch = uiswitch(app.HHController, 'slider');
            app.MainControlSwitch.Items = {'OFF', 'ON'};
            app.MainControlSwitch.ValueChangedFcn = createCallbackFcn(app, @MainControlSwitchValueChanged, true);
            app.MainControlSwitch.Value = 'OFF';
            app.FileforAutomaticEditFieldLabel = uilabel(app.HHController);
            app.FileforAutomaticEditFieldLabel.Text = 'File for Automatic';
            app.FileforAutomaticEditField = uieditfield(app.HHController, 'text');
            app.XFieldSliderLabel = uilabel(app.HHController);
            app.XFieldSliderLabel.Text = 'X-Field';
            app.XFieldSlider = uislider(app.HHController);
            app.XFieldSlider.Limits = [-100 100];
            app.YFieldSliderLabel = uilabel(app.HHController);
            app.YFieldSliderLabel.Text = 'Y-Field';
            app.YFieldSlider = uislider(app.HHController);
            app.YFieldSlider.Limits = [-100 100];
            app.ZFieldSliderLabel = uilabel(app.HHController);
            app.ZFieldSliderLabel.Text = 'Z-Field';
            app.ZFieldSlider = uislider(app.HHController);
            app.ZFieldSlider.Limits = [-100 100];
            app.FieldRequestinuTLabel = uilabel(app.HHController);
            app.FieldRequestinuTLabel.Text = 'Field Request in uT';
            app.MainControlSwitchLabel = uilabel(app.HHController);
            app.MainControlSwitchLabel.Text = 'Main Control';
            app.Label_2 = uilabel(app.HHController);
            app.Label_2.Text = '';
            app.AutoUpdateLamp = uilamp(app.HHController);
            app.ModeDropDownLabel = uilabel(app.HHController);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.HHController);
            app.ModeDropDown.Items = {'Manual', 'Automatic'};
            app.ModeDropDown.Value = 'Manual';
            app.CorrectforAmbientFieldVariationSwitchLabel = uilabel(app.HHController);
            app.CorrectforAmbientFieldVariationSwitchLabel.Text = {'Correct for '; 'Ambient Field'; ' Variation'};
            app.CorrectforAmbientFieldVariationSwitch = uiswitch(app.HHController, 'slider');
            app.StartButton = uibutton(app.HHController, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ModeDropDownLabel_2 = uilabel(app.HHController);
            app.ModeDropDownLabel_2.Text = 'Connection';
            % Show the figure after all components are created
            app.HHController.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HHController)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HHController)
        end
    end
end